classdef activations_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        LayerButtonGroup       matlab.ui.container.ButtonGroup
        Conv4                  matlab.ui.control.RadioButton
        ReLu4                  matlab.ui.control.RadioButton
        Conv8                  matlab.ui.control.RadioButton
        ReLu8                  matlab.ui.control.RadioButton
        Conv16                 matlab.ui.control.RadioButton
        ReLu16                 matlab.ui.control.RadioButton
        Conv32                 matlab.ui.control.RadioButton
        ReLu32                 matlab.ui.control.RadioButton
        Conv64                 matlab.ui.control.RadioButton
        ReLu64                 matlab.ui.control.RadioButton
        SoftMax                matlab.ui.control.RadioButton
        Outout                 matlab.ui.control.RadioButton
        fully_connectedButton  matlab.ui.control.RadioButton
        ActivationButton       matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        ReadyLampLabel         matlab.ui.control.Label
        ReadyLamp              matlab.ui.control.Lamp
        CenterPanel            matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        ButtonGroup            matlab.ui.container.ButtonGroup
        b0                     matlab.ui.control.ToggleButton
        b1                     matlab.ui.control.ToggleButton
        b2                     matlab.ui.control.ToggleButton
        b3                     matlab.ui.control.ToggleButton
        b4                     matlab.ui.control.ToggleButton
        b5                     matlab.ui.control.ToggleButton
        b6                     matlab.ui.control.ToggleButton
        b7                     matlab.ui.control.ToggleButton
        b8                     matlab.ui.control.ToggleButton
        b9                     matlab.ui.control.ToggleButton
        Image2                 matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ActivationButton
        function ActivationButtonPushed(app, event)
           layer_name = app.LayerButtonGroup.SelectedObject.Text;
           number = 0;
           if app.b0.Value
               number = 0;
           end
           if app.b1.Value
               number = 1;
           end
           if app.b2.Value
               number = 2;
           end
           if app.b3.Value
               number = 3;
           end
           if app.b4.Value
               number = 4;
           end
           if app.b5.Value
               number = 5;
           end
           if app.b6.Value
               number = 6;
           end
           if app.b7.Value
               number = 7;
           end
           if app.b8.Value
               number = 8;
           end
           if app.b9.Value
              number = 9; 
           end
           app.ReadyLampLabel.Text = 'Generating';
           app.ReadyLamp.Color = [0.5,0,0];
           app.Image2.ImageSource = ['samples/' num2str(number) '.png'];
           I = create_activations(layer_name,number);
           app.ReadyLampLabel.Text = 'Ready';
           app.ReadyLamp.Color = [0,0.5,0];
           imagesc(app.UIAxes, I)
           app.UIAxes.Color = 'none';
           colormap(app.UIAxes, 'bone');
           axis(app.UIAxes, 'equal');
           colorbar(app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {474, 474, 474};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {474, 474};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {400, '1x', 169};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LayerButtonGroup = uibuttongroup(app.LeftPanel);
            app.LayerButtonGroup.Title = 'Layer';
            app.Conv4 = uiradiobutton(app.LayerButtonGroup);
            app.Conv4.Text = 'conv4';
            app.Conv4.Value = true;
            app.ReLu4 = uiradiobutton(app.LayerButtonGroup);
            app.ReLu4.Text = 'relu4';
            app.Conv8 = uiradiobutton(app.LayerButtonGroup);
            app.Conv8.Text = 'conv8';
            app.ReLu8 = uiradiobutton(app.LayerButtonGroup);
            app.ReLu8.Text = 'relu8';
            app.Conv16 = uiradiobutton(app.LayerButtonGroup);
            app.Conv16.Text = 'conv16';
            app.ReLu16 = uiradiobutton(app.LayerButtonGroup);
            app.ReLu16.Text = 'relu16';
            app.Conv32 = uiradiobutton(app.LayerButtonGroup);
            app.Conv32.Text = 'conv32';
            app.ReLu32 = uiradiobutton(app.LayerButtonGroup);
            app.ReLu32.Text = 'relu32';
            app.Conv64 = uiradiobutton(app.LayerButtonGroup);
            app.Conv64.Text = 'conv64';
            app.ReLu64 = uiradiobutton(app.LayerButtonGroup);
            app.ReLu64.Text = 'relu64';
            app.SoftMax = uiradiobutton(app.LayerButtonGroup);
            app.SoftMax.Text = 'softmax';
            app.Outout = uiradiobutton(app.LayerButtonGroup);
            app.Outout.Text = 'classification_output';
            app.fully_connectedButton = uiradiobutton(app.LayerButtonGroup);
            app.fully_connectedButton.Text = 'fully_connected';
            app.ActivationButton = uibutton(app.LeftPanel, 'push');
            app.ActivationButton.ButtonPushedFcn = createCallbackFcn(app, @ActivationButtonPushed, true);
            app.ActivationButton.Text = 'Activation';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'network_arch.svg';
            app.ReadyLampLabel = uilabel(app.LeftPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Activations')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.RightPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.b0 = uitogglebutton(app.ButtonGroup);
            app.b0.Text = '٠';
            app.b0.Value = true;
            app.b1 = uitogglebutton(app.ButtonGroup);
            app.b1.Text = '١';
            app.b2 = uitogglebutton(app.ButtonGroup);
            app.b2.Text = '٢';
            app.b3 = uitogglebutton(app.ButtonGroup);
            app.b3.Text = '٣';
            app.b4 = uitogglebutton(app.ButtonGroup);
            app.b4.Text = '٤';
            app.b5 = uitogglebutton(app.ButtonGroup);
            app.b5.Text = '٥';
            app.b6 = uitogglebutton(app.ButtonGroup);
            app.b6.Text = '٦';
            app.b7 = uitogglebutton(app.ButtonGroup);
            app.b7.Text = '٧';
            app.b8 = uitogglebutton(app.ButtonGroup);
            app.b8.Text = '٨';
            app.b9 = uitogglebutton(app.ButtonGroup);
            app.b9.Text = '٩';
            app.Image2 = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activations_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end